classdef DeProj_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DeProjGUILabel                  matlab.ui.control.Label
        RunDeprojectionButton           matlab.ui.control.Button
        InputsPanel                     matlab.ui.container.Panel
        SelectsegmentationfileButton    matlab.ui.control.Button
        segLocDisplay                   matlab.ui.control.TextArea
        SelectcurvaturefileplyortifButton  matlab.ui.control.Button
        curveLocDisplay                 matlab.ui.control.TextArea
        ResizeelevationmapmaximumsizepixEditFieldLabel  matlab.ui.control.Label
        ResizeelevationmapmaximumsizepixEditField  matlab.ui.control.NumericEditField
        ResizemeshmaximumsizefacesEditFieldLabel  matlab.ui.control.Label
        ResizemeshmaximumsizefacesEditField  matlab.ui.control.NumericEditField
        AxialvoxelsizeumEditFieldLabel  matlab.ui.control.Label
        AxialvoxelsizeumEditField       matlab.ui.control.NumericEditField
        SelectoutputfolderButton        matlab.ui.control.Button
        outputFolderDisplay             matlab.ui.control.TextArea
        CurvaturetypeDropDownLabel      matlab.ui.control.Label
        CurvaturetypeDropDown           matlab.ui.control.DropDown
        LateralvoxelsizeumEditFieldLabel  matlab.ui.control.Label
        LateralvoxelsizeumEditField     matlab.ui.control.NumericEditField
        DisplaysPanel                   matlab.ui.container.Panel
        Displaytheoriginal2DsegmentationCheckBox  matlab.ui.control.CheckBox
        Displaytheoriginal3DcurvatureCheckBox  matlab.ui.control.CheckBox
        DisplayanoverlayoftheoriginalinputsCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = public)
        doDispSeg = true;
        doDispMesh = true;
        doDispOverlay = true;
        axPixSize = 0.5;
        latPixSize = 0.183;
        tiffImSize = 40000;
        maxFaces = 3000;
        outputFolder = '';
        segLoc = '';
        curveLoc = '';
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            com.mathworks.mwswing.MJFileChooserPerPlatform.setUseSwingDialog(1)
        end

        % Value changed function: AxialvoxelsizeumEditField
        function AxialvoxelsizeumEditFieldValueChanged(app, event)
            app.axPixSize = app.AxialvoxelsizeumEditField.Value;            
        end

        % Value changed function: CurvaturetypeDropDown
        function CurvaturetypeDropDownValueChanged(app, event)
            curvatureType = app.CurvaturetypeDropDown.Value;
            if strcmp(curvatureType,'2D elevation map (.tif)')
                app.ResizeelevationmapmaximumsizepixEditField.Enable = 'on';
                % app.AxialvoxelsizeumEditField.Enable = 'on';
                % app.LateralvoxelsizeumEditField.Enable = 'on';
            elseif strcmp(curvatureType,'3D mesh (.ply)')
                app.ResizeelevationmapmaximumsizepixEditField.Enable = 'off';
                % app.AxialvoxelsizeumEditField.Enable = 'off';
                % app.LateralvoxelsizeumEditField.Enable = 'off';
            end
        end

        % Value changed function: 
        % DisplayanoverlayoftheoriginalinputsCheckBox
        function DisplayanoverlayoftheoriginalinputsCheckBoxValueChanged(app, event)
            app.doDispOverlay = app.DisplayanoverlayoftheoriginalinputsCheckBox.Value;
        end

        % Value changed function: 
        % Displaytheoriginal2DsegmentationCheckBox
        function Displaytheoriginal2DsegmentationCheckBoxValueChanged(app, event)
            app.doDispSeg = app.Displaytheoriginal2DsegmentationCheckBox.Value;
        end

        % Value changed function: 
        % Displaytheoriginal3DcurvatureCheckBox
        function Displaytheoriginal3DcurvatureCheckBoxValueChanged(app, event)
            app.doDispMesh = app.Displaytheoriginal3DcurvatureCheckBox.Value;
        end

        % Value changed function: 
        % ResizeelevationmapmaximumsizepixEditField
        function ResizeelevationmapmaximumsizepixEditFieldValueChanged(app, event)
            app.tiffImSize = app.ResizeelevationmapmaximumsizepixEditField.Value;
        end

        % Value changed function: ResizemeshmaximumsizefacesEditField
        function ResizemeshmaximumsizefacesEditFieldValueChanged(app, event)
            app.maxFaces = app.ResizemeshmaximumsizefacesEditField.Value;            
        end

        % Button pushed function: RunDeprojectionButton
        function RunDeprojectionButtonPushed(app, event)
            
            voxSize = [ app.latPixSize, app.axPixSize ];
            
            fprintf('app.doDispSeg = %s\n',app.doDispSeg);
            fprintf('app.doDispMesh = %s\n',app.doDispMesh);
            fprintf('app.doDispOverlay = %s\n',app.doDispOverlay);
            fprintf('app.latPixSize = %d\n',app.latPixSize);
            fprintf('app.axPixSize = %d\n',app.axPixSize);
            fprintf('app.tiffImSize = %d\n',app.tiffImSize);
            fprintf('app.maxFaces = %d\n',app.maxFaces);
            fprintf('app.outputFolder = %s\n',app.outputFolder);
            fprintf('app.segLoc = %s\n',app.segLoc);
            fprintf('app.curveLoc = %s\n',app.curveLoc);
            surface3D_combine(app.doDispSeg,...
                app.doDispMesh,...
                app.doDispOverlay,...
                voxSize,...
                app.tiffImSize,...
                app.maxFaces,...
                app.outputFolder,...
                app.segLoc,...
                app.curveLoc);
        end

        % Button pushed function: SelectcurvaturefileplyortifButton
        function SelectcurvaturefileplyortifButtonPushed(app, event)
            [file, path] = uigetfile({'*.ply' ; '*.tif'}, 'Select the file containing the sample curvature');
%             if isequal(file,0)
%                disp('User selected Cancel');
%             else
%                disp(['User selected ', fullfile(path,file)]);
%             end
            app.curveLoc = fullfile(path,file);
            app.curveLocDisplay.Value = app.curveLoc;
        end

        % Button pushed function: SelectoutputfolderButton
        function SelectoutputfolderButtonPushed(app, event)
            app.outputFolder = uigetdir(pwd,'Select the output folder');
            app.outputFolderDisplay.Value = app.outputFolder;
        end

        % Button pushed function: SelectsegmentationfileButton
        function SelectsegmentationfileButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat'}, 'Select the file containing the segmentation');
            app.segLoc = fullfile(path,file);
            app.segLocDisplay.Value = app.segLoc;
        end

        % Value changed function: LateralvoxelsizeumEditField
        function LateralvoxelsizeumEditFieldValueChanged(app, event)
            app.latPixSize = app.LateralvoxelsizeumEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DeProjGUILabel = uilabel(app.UIFigure);
            app.DeProjGUILabel.Text = 'DeProj GUI';
            app.RunDeprojectionButton = uibutton(app.UIFigure, 'push');
            app.RunDeprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunDeprojectionButtonPushed, true);
            app.RunDeprojectionButton.Text = 'Run Deprojection';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.SelectsegmentationfileButton = uibutton(app.InputsPanel, 'push');
            app.SelectsegmentationfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsegmentationfileButtonPushed, true);
            app.SelectsegmentationfileButton.Text = 'Select segmentation file';
            app.segLocDisplay = uitextarea(app.InputsPanel);
            app.segLocDisplay.Editable = 'off';
            app.SelectcurvaturefileplyortifButton = uibutton(app.InputsPanel, 'push');
            app.SelectcurvaturefileplyortifButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcurvaturefileplyortifButtonPushed, true);
            app.SelectcurvaturefileplyortifButton.Text = 'Select curvature file (.ply or .tif)';
            app.curveLocDisplay = uitextarea(app.InputsPanel);
            app.curveLocDisplay.Editable = 'off';
            app.ResizeelevationmapmaximumsizepixEditFieldLabel = uilabel(app.InputsPanel);
            app.ResizeelevationmapmaximumsizepixEditFieldLabel.Text = 'Resize elevation map maximum size (pix) ';
            app.ResizeelevationmapmaximumsizepixEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ResizeelevationmapmaximumsizepixEditField.ValueDisplayFormat = '%.0f';
            app.ResizeelevationmapmaximumsizepixEditField.ValueChangedFcn = createCallbackFcn(app, @ResizeelevationmapmaximumsizepixEditFieldValueChanged, true);
            app.ResizeelevationmapmaximumsizepixEditField.Value = 40000;
            app.ResizemeshmaximumsizefacesEditFieldLabel = uilabel(app.InputsPanel);
            app.ResizemeshmaximumsizefacesEditFieldLabel.Text = 'Resize mesh maximum size (faces) ';
            app.ResizemeshmaximumsizefacesEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ResizemeshmaximumsizefacesEditField.ValueDisplayFormat = '%.0f';
            app.ResizemeshmaximumsizefacesEditField.ValueChangedFcn = createCallbackFcn(app, @ResizemeshmaximumsizefacesEditFieldValueChanged, true);
            app.ResizemeshmaximumsizefacesEditField.Value = 3000;
            app.AxialvoxelsizeumEditFieldLabel = uilabel(app.InputsPanel);
            app.AxialvoxelsizeumEditFieldLabel.Text = 'Axial voxel size (um)';
            app.AxialvoxelsizeumEditField = uieditfield(app.InputsPanel, 'numeric');
            app.AxialvoxelsizeumEditField.ValueDisplayFormat = '%.3f';
            app.AxialvoxelsizeumEditField.ValueChangedFcn = createCallbackFcn(app, @AxialvoxelsizeumEditFieldValueChanged, true);
            app.AxialvoxelsizeumEditField.Value = 1;
            app.SelectoutputfolderButton = uibutton(app.InputsPanel, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputfolderButtonPushed, true);
            app.SelectoutputfolderButton.Text = 'Select output folder';
            app.outputFolderDisplay = uitextarea(app.InputsPanel);
            app.outputFolderDisplay.Editable = 'off';
            app.CurvaturetypeDropDownLabel = uilabel(app.InputsPanel);
            app.CurvaturetypeDropDownLabel.Text = 'Curvature type';
            app.CurvaturetypeDropDown = uidropdown(app.InputsPanel);
            app.CurvaturetypeDropDown.Items = {'2D elevation map (.tif)', '3D mesh (.ply)'};
            app.CurvaturetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CurvaturetypeDropDownValueChanged, true);
            app.CurvaturetypeDropDown.Value = '2D elevation map (.tif)';
            app.LateralvoxelsizeumEditFieldLabel = uilabel(app.InputsPanel);
            app.LateralvoxelsizeumEditFieldLabel.Text = 'Lateral voxel size (um)';
            app.LateralvoxelsizeumEditField = uieditfield(app.InputsPanel, 'numeric');
            app.LateralvoxelsizeumEditField.ValueDisplayFormat = '%.3f';
            app.LateralvoxelsizeumEditField.ValueChangedFcn = createCallbackFcn(app, @LateralvoxelsizeumEditFieldValueChanged, true);
            app.LateralvoxelsizeumEditField.Value = 0.183;
            app.DisplaysPanel = uipanel(app.UIFigure);
            app.DisplaysPanel.Title = 'Displays';
            app.Displaytheoriginal2DsegmentationCheckBox = uicheckbox(app.DisplaysPanel);
            app.Displaytheoriginal2DsegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @Displaytheoriginal2DsegmentationCheckBoxValueChanged, true);
            app.Displaytheoriginal2DsegmentationCheckBox.Text = 'Display the original 2D segmentation';
            app.Displaytheoriginal3DcurvatureCheckBox = uicheckbox(app.DisplaysPanel);
            app.Displaytheoriginal3DcurvatureCheckBox.ValueChangedFcn = createCallbackFcn(app, @Displaytheoriginal3DcurvatureCheckBoxValueChanged, true);
            app.Displaytheoriginal3DcurvatureCheckBox.Text = 'Display the original 3D curvature';
            app.DisplayanoverlayoftheoriginalinputsCheckBox = uicheckbox(app.DisplaysPanel);
            app.DisplayanoverlayoftheoriginalinputsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayanoverlayoftheoriginalinputsCheckBoxValueChanged, true);
            app.DisplayanoverlayoftheoriginalinputsCheckBox.Text = 'Display an overlay of the original inputs';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DeProj_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end